classdef itree_vis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        StartButton          matlab.ui.control.Button
        DecreaseDepthButton  matlab.ui.control.Button
        IncreaseDepthButton  matlab.ui.control.Button
        PleasepresstheStartButtontovisualizespacepartitioningLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        prev_level
        itree
        planes
    end
    

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            examples = load('examples.mat'); examples = examples.examples;
            target = load('target.mat'); target = target.target;
            app.itree = get_random_itree(examples);
            app.prev_level = 0;
            scatter3(app.UIAxes, examples(:, 1), examples(:, 2), examples(:, 3), 30, categorical(target'), 'filled');
            hold(app.UIAxes, 'on' );
            delete(app.PleasepresstheStartButtontovisualizespacepartitioningLabel);
            delete(app.StartButton);
        end

        % Button pushed function: IncreaseDepthButton
        function IncreaseDepthButtonPushed(app, event)
            % TODO delete all planes
            app.planes = linear_partitioning_visualization(app.itree, [0, 1; 0, 1; 0, 1], app.prev_level+1, app.UIAxes);
            app.prev_level = app.prev_level + 1;
        end

        % Button pushed function: DecreaseDepthButton
        function DecreaseDepthButtonPushed(app, event)
            % TODO delete all planes
            if app.prev_level > 0
               app.prev_level = app.prev_level - 1;
               app.planes = linear_partitioning_visualization(app.itree, [0, 1; 0, 1; 0, 1], app.prev_level+1, app.UIAxes);
            end
            % Else do nothing
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DecreaseDepthButton = uibutton(app.UIFigure, 'push');
            app.DecreaseDepthButton.ButtonPushedFcn = createCallbackFcn(app, @DecreaseDepthButtonPushed, true);
            app.DecreaseDepthButton.Text = 'Decrease Depth';
            app.IncreaseDepthButton = uibutton(app.UIFigure, 'push');
            app.IncreaseDepthButton.ButtonPushedFcn = createCallbackFcn(app, @IncreaseDepthButtonPushed, true);
            app.IncreaseDepthButton.Text = 'Increase Depth';
            app.PleasepresstheStartButtontovisualizespacepartitioningLabel = uilabel(app.UIFigure);
            app.PleasepresstheStartButtontovisualizespacepartitioningLabel.Text = 'Please press the Start Button to visualize space partitioning.';
        end
    end

    methods (Access = public)

        % Construct app
        function app = itree_vis

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end